classdef Respiration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        InitializeTab                 matlab.ui.container.Tab
        HeartRatebeatsperminuteLabel  matlab.ui.control.Label
        RespirationRatebreathperminuteLabel  matlab.ui.control.Label
        StopLiveReadingButton         matlab.ui.control.Button
        StartLiveReadingButton        matlab.ui.control.Button
        InitializeSensorsButton       matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        SignInTab                     matlab.ui.container.Tab
        UserNameDropDown              matlab.ui.control.DropDown
        UserNameDropDownLabel         matlab.ui.control.Label
        SignInButton                  matlab.ui.control.Button
        CalibrationTab                matlab.ui.container.Tab
        Activity1Tab                  matlab.ui.container.Tab
        HRLabel                       matlab.ui.control.Label
        ScoreLabel                    matlab.ui.control.Label
        InstructionsField             matlab.ui.control.Label
        PlayTutorialButton            matlab.ui.control.Button
        DiscardScoreButton            matlab.ui.control.Button
        StopButton_2                  matlab.ui.control.Button
        CompleteLabel                 matlab.ui.control.Label
        StatusLabel                   matlab.ui.control.Label
        TimestampFieldLabel           matlab.ui.control.Label
        TimestampLabel                matlab.ui.control.Label
        UsernameFieldLabel            matlab.ui.control.Label
        UsernameLabel                 matlab.ui.control.Label
        countNum                      matlab.ui.control.Label
        CountLabel                    matlab.ui.control.Label
        TimeNum                       matlab.ui.control.Label
        TimeLabel                     matlab.ui.control.Label
        SaveScoreButton               matlab.ui.control.Button
        PauseButton                   matlab.ui.control.Button
        StartButton_2                 matlab.ui.control.Button
        Activity1Complete5diaphragmaticbreathsLabel  matlab.ui.control.Label
        UIAxes_A1                     matlab.ui.control.UIAxes
        Activity2Tab                  matlab.ui.container.Tab
        Round3Label                   matlab.ui.control.Label
        Round2Label                   matlab.ui.control.Label
        Round1Label                   matlab.ui.control.Label
        ProgressLabel                 matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        InstructionsField_A2          matlab.ui.control.Label
        HRLabel_A2                    matlab.ui.control.Label
        ScoreLabel_A2                 matlab.ui.control.Label
        PlayTutorialButton_A2         matlab.ui.control.Button
        DiscardScoreButton_A2         matlab.ui.control.Button
        StopButton_A2                 matlab.ui.control.Button
        CompleteLabel_A2              matlab.ui.control.Label
        StatusLabel_A2                matlab.ui.control.Label
        TimestampFieldLabel_A2        matlab.ui.control.Label
        TimestampLabel_A2             matlab.ui.control.Label
        UsernameFieldLabel_A2         matlab.ui.control.Label
        UsernameLabel_A2              matlab.ui.control.Label
        SaveScoreButton_A2            matlab.ui.control.Button
        PauseButton_A2                matlab.ui.control.Button
        StartButton_A2                matlab.ui.control.Button
        Activity2Label                matlab.ui.control.Label
        UIAxes_A2_ProgressBar         matlab.ui.control.UIAxes
        UIAxes_A2                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        d %DAQ Interface
        dInfo %DAQ info
        fname; %file to store data
        recorded; %saved data
        calibration; %calibration matrix
        threshold; %peak threshold
        mTimer; %timer object
        mCounter; %integer counter
        pbCounter; %progress bar counter

    end
    
    methods (Access = private)
        
        function plotAcc(app, src, evt)
            [data, ts, ~]=read(src, src.ScansAvailableFcnCount,"OutputFormat","Matrix");
         
            yyaxis(app.UIAxes2,'left');
            plot(app.UIAxes2, ts, data,'Marker','none');
       
            app.recorded=[app.recorded; ts data];


        end

        
        function growCircle(app,ax,thr_circle)
            
            hold(ax,'on');
            inc=[.75:0.01:2.25];
            d_color=1/length(inc);
            rgb_colors=[1:-d_color:d_color; d_color:d_color:1; zeros(1,length(inc))];
            t_pause=exp(linspace(log(0.00005), log(0.3),length(inc)));
            for i=1:length(inc)
                axis(ax,[0 6 0 6]);

                if(thr_circle)
                    viscircles(ax,[3 3],1.9,'Color',rgb_colors(:,find(inc==1.9)),'LineStyle','--');
                    if(i==find(inc==1.9)-1)
                        app.countNum.Text=num2str(1);
                    end
                end
                
                viscircles(ax,[3 3],inc(i),'Color',rgb_colors(:,i));
                
                hold(ax,'off');
                pause(t_pause(i));
            end
        end

        function shrinkCircle(app,ax,thr_circle)
          
            hold(ax,'on');
            inc=[2.25:-0.01:.75];
            d_color=1/length(inc);
            rgb_colors=[d_color:d_color:1; 1:-d_color:d_color; zeros(1,length(inc))];
            t_pause=exp(linspace(log(0.0000001), log(0.0001),length(inc)));
            for i=1:length(inc)
                axis(ax,[0 6 0 6]);

                if(thr_circle)
                    viscircles(ax,[3 3],1.9,'Color',rgb_colors(:,find(inc==1.9)),'LineStyle','--');
                end
                
                viscircles(ax,[3 3],inc(i),'Color',rgb_colors(:,i));
                
                hold(ax,'off');
                pause(t_pause(i));
            end
        end

        function grow_shrinkCircle(app,ax)
           
            inc=[2.25:-0.01:.75];
            d_color=1/length(inc);
            rgb_colors=[d_color:d_color:1; 1:-d_color:d_color; zeros(1,length(inc))];

            viscircles(ax,[3 3],1.9,'Color',rgb_colors(:,find(inc==1.9)),'LineStyle','--');
            pause(3);
            hold(ax,'on');
            growCircle(app,ax,1);
            shrinkCircle(app,ax,1);
        end

        function grow_shrinkCircle2(app,ax)
           
            inc=[2.25:-0.01:.75];
            d_color=1/length(inc);
            rgb_colors=[d_color:d_color:1; 1:-d_color:d_color; zeros(1,length(inc))];

            viscircles(ax,[3 3],1.9,'Color',rgb_colors(:,find(inc==1.9)),'LineStyle','--');
            pause(3);
            hold(ax,'on');
            growCircle(app,ax,1);
            shrinkCircle(app,ax,1);

            % inc=[2.25:-0.01:.75];
            % d_color=1/length(inc);
            % rgb_colors=[d_color:d_color:1; 1:-d_color:d_color; zeros(1,length(inc))];
            % 
            % viscircles(app.UIAxes_A2,[3 3],1.9,'Color',rgb_colors(:,find(inc==1.9)),'LineStyle','--');
            % pause(3);
            % 
            % app.TimeNum_A2.Text='0:30';
            % 
            % for i=1:30
            %     tStart = cputime;
            %     app.TimeNum_A2.Text=append('0:',num2str(31-i));
            %     hold(app.UIAxes_A2,'on');
            %     if(rem(i,5)==0)
            %         growCircle(app,1);
            %         shrinkCircle(app,1);
            %     end
            %     tEnd = cputime - tStart;
            %     pause(1-tEnd);
            % 
            % end
        end

        function startTimer(app,startT, endT)
            app.mCounter = startT; %start time in seconds
            
            round=1;
            on_off=[ones(30,1) zeros(30,1) ones(30,1)*2 zeros(30,1) ones(30,1)*3 zeros(30,1)];
            app.mTimer = timer('TimerFcn', @timerCallback, 'ExecutionMode', 'FixedRate', 'Period', .1);
            start(app.mTimer);
            
            
            % this is the callback for the timer
            function timerCallback(~, ~)
                if app.mCounter>=endT
                    % update the text area and increment the timer
                    app.TextArea.Value = num2str(app.mCounter);
                    app.mCounter = app.mCounter - 1;
                    if(on_off(180-app.mCounter))
                        round=on_off(180-app.mCounter);
                        xline(app.UIAxes_A2_ProgressBar,180-app.mCounter,'Color','g','LineWidth',5);
                        app.ProgressLabel.Text=append('Progress: Round ',num2str(round),' Diaphragmatic Breathing');
                    else
                        xline(app.UIAxes_A2_ProgressBar,180-app.mCounter,'Color',[0.54, 0.70, 0.67],'LineWidth',5);
                        app.ProgressLabel.Text=append('Progress: Round ',num2str(round),' Rest');
                    end

                else
                    stop(app.mTimer);
                end
            
            end

        end

        function grow_shrinkCircleTimer(app)
            
            
            startTimer(app, 180, 121);
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function preLoad(app)

            % yyaxis(app.UIAxes,'left');
            % ylabel(app.UIAxes, 'X,Y Acc(G)');

        end

        % Button pushed function: InitializeSensorsButton
        function initDAQ(app, event)
            mydaq=daq("adi"); %create DAQ Object called mydaq
            addinput(mydaq,'smu1','a','Voltage'); %Add chA as the input
            % addinput(mydaq,'smu1','b','Voltage'); %Add chB as the input
            mydaq.ScansAvailableFcnCount=0.5*mydaq.Rate; %set samples aquired to 0.5s
            app.d=mydaq; %store DAQ object to app property d
            app.dInfo=mydaq.Channels.Device; %store DAQ properties to app property dInfo
          
            disp('DAQ initialized');
        end

        % Button pushed function: StartLiveReadingButton
        function startDAQ(app, event)
            %call plotTemp when enough scans are available
            %Aqcuiring and plotting
            % create callback function to call plotPPG every time 2s scans available
            app.d.ScansAvailableFcn = @(src,evt) plotAcc(app,src,evt);    
            % start acquiring and plotting
            start(app.d, "continuous");

            disp('DAQ started');

        end

        % Button pushed function: StopLiveReadingButton
        function stopDAQ(app, event)
            %stop DAQ
            %Clear DAQ buffer and axes

            stop(app.d);                      %stop daq
            flush(app.d);                     %clear data in daq buffer
            cla(app.UIAxes);
            disp('DAQ stopped');
        end

        % Callback function: not associated with a component
        function saveData(app, event)
            data=app.recorded;
            save(app.FileNameEditField.Value,'data');
        end

        % Callback function: not associated with a component
        function startRecord(app, event)
            value = app.RecordButton.Value;
            app.recorded=[];
        end

        % Callback function: not associated with a component
        function addCircle(app, event)
            viscircles(app.UIAxes_A1,[3 3],2,'Color','g','LineStyle','--');
            hold(app.UIAxes_A1,'on');
            inc=[0.1:0.01:2.5];
            for i=1:length(inc)
                %cla(app.UIAxes_A1);
                axis(app.UIAxes_A1,[0 6 0 6]);
                viscircles(app.UIAxes_A1,[3 3],inc(i));
                viscircles(app.UIAxes_A1,[3 3],2,'Color','g','LineStyle','--');
                hold(app.UIAxes_A1,'off');
                pause(0.01);
            end
        end

        % Button down function: Activity1Tab
        function initActivity1(app, event)
            cla(app.UIAxes_A1);
            axis(app.UIAxes_A1,[0 6 0 6]);
            % viscircles(app.UIAxes_A1,[3 3],2,'Color','g','LineStyle','--');
            hold(app.UIAxes_A1,'on');
            app.CountLabel.Text='Count:';
            app.countNum.Text='0';
            app.TimeLabel.Text='Time Remaining:';
            app.TimeNum.Text='2:00';
            
        end

        % Button pushed function: PlayTutorialButton
        function startTutorial(app, event)

            %The red circle represents your breathing
            app.CountLabel.Text='';
            app.countNum.Text='';
            app.TimeLabel.Text='';
            app.TimeNum.Text='';
            app.InstructionsField.Text='The red circle represents your breathing.';
            viscircles(app.UIAxes_A1,[3 3],.75,'Color','r');
            pause(3);
            %When you breathe in, the circle will grow
            app.InstructionsField.Text='When you breathe in, the circle will grow.';
            pause(3);
            growCircle(app,app.UIAxes_A1,0);
           
            %When you breathe out, the circle will shrink
            app.InstructionsField.Text='When you breathe out, the circle will shrink.';
            pause(3);
            shrinkCircle(app,app.UIAxes_A1,0);
            
            %The dashed green circle is your goal. Every breath that
            %crosses this threshold will be counted as one breath.
            app.CountLabel.Text='Count:';
            app.countNum.Text='0';
            app.InstructionsField.Text='The dashed green circle is your goal. Every inhale that crosses this threshold will be counted as one breath.';
            

            grow_shrinkCircle(app,app.UIAxes_A1);

            pause(1);
            %You have 2mins to reach 5 successful diaphragmatic breaths

            app.TimeLabel.Text='Time Remaining:';
            app.TimeNum.Text='2:00';
            
            app.InstructionsField.Text='You have 2mins to finish 5 successful diaphragmatic breaths.';
            pause(5);
            
            cla(app.UIAxes_A1);
            app.CountLabel.Text='Count:';
            app.countNum.Text='0';
            app.TimeLabel.Text='Time Remaining:';
            app.TimeNum.Text='2:00';
            app.InstructionsField.Text='';
        
        end

        % Button pushed function: PlayTutorialButton_A2
        function startTutorial2(app, event)

            app.InstructionsField_A2.Text='The dashed green circle is your goal. Every inhale that crosses this threshold will be counted as one breath.';
            
            grow_shrinkCircle2(app,app.UIAxes_A2);

            app.InstructionsField_A2.Text='We will do 3 x 30s Rounds of diaphragmatic breathing. You will have 30s of rest after each round.';

            grow_shrinkCircleTimer(app);

        end

        % Callback function: not associated with a component
        function CheckBoxValueChange(app, event)
            value = app.TimerCheckBox.Value;
            if value == 1
                % checkbox is checked so reset counter, create and start
                % timer
                app.mCounter = 30;
                app.mTimer = timer('TimerFcn', @timerCallback, 'ExecutionMode', 'FixedRate', 'Period', 1);
                start(app.mTimer);
            else
                % checkbox is unchecked so stop timer
                stop(app.mTimer);
            end
            
            % this is the callback for the timer
            function timerCallback(~, ~)
                if app.mCounter>=0
                    % update the text area and increment the timer
                    app.TextArea.Value = num2str(app.mCounter);
                    app.mCounter = app.mCounter - 1;


                end
            end
        end

        % Button down function: Activity2Tab
        function initActivity2(app, event)
            cla(app.UIAxes_A2);
            axis(app.UIAxes_A2,[0 6 0 6]);
     
            hold(app.UIAxes_A2,'on');

            cla(app.UIAxes_A2_ProgressBar);
            axis(app.UIAxes_A2_ProgressBar,[0 180 0 10]);

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InitializeTab = uitab(app.TabGroup);
            app.InitializeTab.Title = 'Initialize';
            app.UIAxes = uiaxes(app.InitializeTab);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Breathing (AU)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.InitializeTab);
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'PPG')
            zlabel(app.UIAxes2, 'Z')
            app.InitializeSensorsButton = uibutton(app.InitializeTab, 'push');
            app.InitializeSensorsButton.ButtonPushedFcn = createCallbackFcn(app, @initDAQ, true);
            app.InitializeSensorsButton.Text = 'Initialize Sensors';
            app.StartLiveReadingButton = uibutton(app.InitializeTab, 'push');
            app.StartLiveReadingButton.ButtonPushedFcn = createCallbackFcn(app, @startDAQ, true);
            app.StartLiveReadingButton.Text = 'Start Live Reading';
            app.StopLiveReadingButton = uibutton(app.InitializeTab, 'push');
            app.StopLiveReadingButton.ButtonPushedFcn = createCallbackFcn(app, @stopDAQ, true);
            app.StopLiveReadingButton.Text = 'Stop Live Reading';
            app.RespirationRatebreathperminuteLabel = uilabel(app.InitializeTab);
            app.RespirationRatebreathperminuteLabel.Text = 'Respiration Rate (breath per minute):';
            app.HeartRatebeatsperminuteLabel = uilabel(app.InitializeTab);
            app.HeartRatebeatsperminuteLabel.Text = 'Heart Rate (beats per minute):';
            app.SignInTab = uitab(app.TabGroup);
            app.SignInTab.Title = 'Sign In';
            app.SignInButton = uibutton(app.SignInTab, 'push');
            app.SignInButton.Text = 'Sign In';
            app.UserNameDropDownLabel = uilabel(app.SignInTab);
            app.UserNameDropDownLabel.Text = 'UserName';
            app.UserNameDropDown = uidropdown(app.SignInTab);
            app.UserNameDropDown.Editable = 'on';
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.Activity1Tab = uitab(app.TabGroup);
            app.Activity1Tab.Title = 'Activity 1';
            app.Activity1Tab.ButtonDownFcn = createCallbackFcn(app, @initActivity1, true);
            app.UIAxes_A1 = uiaxes(app.Activity1Tab);
            app.UIAxes_A1.XTick = [];
            app.UIAxes_A1.YTick = [];
            app.UIAxes_A1.ZTick = [];
            app.Activity1Complete5diaphragmaticbreathsLabel = uilabel(app.Activity1Tab);
            app.Activity1Complete5diaphragmaticbreathsLabel.Text = 'Activity 1: Complete 5 diaphragmatic breaths';
            app.StartButton_2 = uibutton(app.Activity1Tab, 'push');
            app.StartButton_2.Text = 'Start';
            app.PauseButton = uibutton(app.Activity1Tab, 'push');
            app.PauseButton.Text = 'Pause';
            app.SaveScoreButton = uibutton(app.Activity1Tab, 'push');
            app.SaveScoreButton.Text = 'Save Score';
            app.TimeLabel = uilabel(app.Activity1Tab);
            app.TimeLabel.Text = '';
            app.TimeNum = uilabel(app.Activity1Tab);
            app.TimeNum.Text = '';
            app.CountLabel = uilabel(app.Activity1Tab);
            app.CountLabel.Text = '';
            app.countNum = uilabel(app.Activity1Tab);
            app.countNum.Text = '';
            app.UsernameLabel = uilabel(app.Activity1Tab);
            app.UsernameLabel.Text = 'Username:';
            app.UsernameFieldLabel = uilabel(app.Activity1Tab);
            app.UsernameFieldLabel.Text = 'UsernameField';
            app.TimestampLabel = uilabel(app.Activity1Tab);
            app.TimestampLabel.Text = 'Timestamp:';
            app.TimestampFieldLabel = uilabel(app.Activity1Tab);
            app.TimestampFieldLabel.Text = 'TimestampField';
            app.StatusLabel = uilabel(app.Activity1Tab);
            app.StatusLabel.Text = 'Status:';
            app.CompleteLabel = uilabel(app.Activity1Tab);
            app.CompleteLabel.Text = 'Complete';
            app.StopButton_2 = uibutton(app.Activity1Tab, 'push');
            app.StopButton_2.Text = 'Stop';
            app.DiscardScoreButton = uibutton(app.Activity1Tab, 'push');
            app.DiscardScoreButton.Text = 'Discard Score';
            app.PlayTutorialButton = uibutton(app.Activity1Tab, 'push');
            app.PlayTutorialButton.ButtonPushedFcn = createCallbackFcn(app, @startTutorial, true);
            app.PlayTutorialButton.Text = 'Play Tutorial';
            app.InstructionsField = uilabel(app.Activity1Tab);
            app.InstructionsField.Text = '';
            app.ScoreLabel = uilabel(app.Activity1Tab);
            app.ScoreLabel.Text = 'Score: ';
            app.HRLabel = uilabel(app.Activity1Tab);
            app.HRLabel.Text = 'HR:';
            app.Activity2Tab = uitab(app.TabGroup);
            app.Activity2Tab.Title = 'Activity 2';
            app.Activity2Tab.ButtonDownFcn = createCallbackFcn(app, @initActivity2, true);
            app.UIAxes_A2 = uiaxes(app.Activity2Tab);
            app.UIAxes_A2.XTick = [];
            app.UIAxes_A2.YTick = [];
            app.UIAxes_A2.ZTick = [];
            app.UIAxes_A2_ProgressBar = uiaxes(app.Activity2Tab);
            app.UIAxes_A2_ProgressBar.XLim = [0 180];
            app.UIAxes_A2_ProgressBar.XTick = [0 30 60 90 120 150 180];
            app.UIAxes_A2_ProgressBar.YTick = [];
            app.UIAxes_A2_ProgressBar.ZTick = [];
            app.Activity2Label = uilabel(app.Activity2Tab);
            app.Activity2Label.Text = 'Activity 2: On/Off Circuits';
            app.StartButton_A2 = uibutton(app.Activity2Tab, 'push');
            app.StartButton_A2.Text = 'Start';
            app.PauseButton_A2 = uibutton(app.Activity2Tab, 'push');
            app.PauseButton_A2.Text = 'Pause';
            app.SaveScoreButton_A2 = uibutton(app.Activity2Tab, 'push');
            app.SaveScoreButton_A2.Text = 'Save Score';
            app.UsernameLabel_A2 = uilabel(app.Activity2Tab);
            app.UsernameLabel_A2.Text = 'Username:';
            app.UsernameFieldLabel_A2 = uilabel(app.Activity2Tab);
            app.UsernameFieldLabel_A2.Text = 'UsernameField';
            app.TimestampLabel_A2 = uilabel(app.Activity2Tab);
            app.TimestampLabel_A2.Text = 'Timestamp:';
            app.TimestampFieldLabel_A2 = uilabel(app.Activity2Tab);
            app.TimestampFieldLabel_A2.Text = 'TimestampField';
            app.StatusLabel_A2 = uilabel(app.Activity2Tab);
            app.StatusLabel_A2.Text = 'Status:';
            app.CompleteLabel_A2 = uilabel(app.Activity2Tab);
            app.CompleteLabel_A2.Text = 'Complete';
            app.StopButton_A2 = uibutton(app.Activity2Tab, 'push');
            app.StopButton_A2.Text = 'Stop';
            app.DiscardScoreButton_A2 = uibutton(app.Activity2Tab, 'push');
            app.DiscardScoreButton_A2.Text = 'Discard Score';
            app.PlayTutorialButton_A2 = uibutton(app.Activity2Tab, 'push');
            app.PlayTutorialButton_A2.ButtonPushedFcn = createCallbackFcn(app, @startTutorial2, true);
            app.PlayTutorialButton_A2.Text = 'Play Tutorial';
            app.ScoreLabel_A2 = uilabel(app.Activity2Tab);
            app.ScoreLabel_A2.Text = 'Score: ';
            app.HRLabel_A2 = uilabel(app.Activity2Tab);
            app.HRLabel_A2.Text = 'HR:';
            app.InstructionsField_A2 = uilabel(app.Activity2Tab);
            app.InstructionsField_A2.Text = '';
            app.TextArea = uitextarea(app.Activity2Tab);
            app.ProgressLabel = uilabel(app.Activity2Tab);
            app.ProgressLabel.Text = '';
            app.Round1Label = uilabel(app.Activity2Tab);
            app.Round1Label.Text = 'Round 1: 0';
            app.Round2Label = uilabel(app.Activity2Tab);
            app.Round2Label.Text = 'Round 2: 0';
            app.Round3Label = uilabel(app.Activity2Tab);
            app.Round3Label.Text = 'Round 3: 0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Respiration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @preLoad)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end